classdef NLX_Event_Finder_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NLXEventFinderUIFigure  matlab.ui.Figure
        Toolbar                 matlab.ui.container.Toolbar
        foldSel                 matlab.ui.container.toolbar.PushTool
        EventListListBoxLabel   matlab.ui.control.Label
        EventList               matlab.ui.control.ListBox
        StatusUpdateLabel       matlab.ui.control.Label
        statusMess              matlab.ui.control.TextArea
        DateLabel               matlab.ui.control.Label
        StartTimeLabel          matlab.ui.control.Label
        dateField               matlab.ui.control.EditField
        startTfield             matlab.ui.control.EditField
        ContextMain             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
        ContextEditMessage      matlab.ui.container.ContextMenu
        Back2top                matlab.ui.container.Menu
        Clear                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        eventLoc % Location for event files
        nevLIST
        timestamps
        ttl
        header
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.statusMess.Value = 'Status UPDATES:';
        end

        % Clicked callback: foldSel
        function foldSelClicked(app, event)
            
            nevCheck = 1;
            while nevCheck
                [app.eventLoc] = uigetdir('title','Select folder with Events file');
                
                cd(app.eventLoc)
                
                nevDIRa = dir("*.nev");
                nevDIRb = {nevDIRa.name};
                
                if isempty(nevDIRb)
                    continue
                else
                    nevCheck = 0;
                end
            end
            
            app.nevLIST = nevDIRb;
            app.EventList.Items = ['...',app.nevLIST];
            app.EventList.Enable = 'on';
            
        end

        % Value changed function: EventList
        function EventListValueChanged(app, event)
            value = app.EventList.Value;
            
            if strcmp(value,'...')
                return
            else
                [Timestamps, ~, TTLs, ~, ~, evHeader] =...
                    Nlx2MatEV('Events.nev', [1 1 1 1 1], 1, 1, [] );
                
                app.timestamps = Timestamps;
                app.ttl = TTLs;
                app.header = evHeader;
                
            end
            
            tmpDate = app.header(contains(app.header,'TimeCreated'));
            dateParts = strsplit(tmpDate{1},' ');
            
            app.dateField.Value = dateParts{2};
            app.startTfield.Value = dateParts{3};
            
        end

        % Menu selected function: Back2top
        function Back2topMenuSelected(app, event)
            scroll(app.statusMess, 'top')
        end

        % Menu selected function: Clear
        function ClearMenuSelected(app, event)
            app.statusMess.Value = 'Status UPDATES:';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NLXEventFinderUIFigure = uifigure('Visible', 'off');
            app.NLXEventFinderUIFigure.Name = 'NLX Event Finder';
            app.NLXEventFinderUIFigure.Icon = 'icon.png';
            app.Toolbar = uitoolbar(app.NLXEventFinderUIFigure);
            app.foldSel = uipushtool(app.Toolbar);
            app.foldSel.Tooltip = {'Open Experiment folder location.'};
            app.foldSel.ClickedCallback = createCallbackFcn(app, @foldSelClicked, true);
            app.foldSel.Icon = 'FolderOpenIconF.png';
            app.EventListListBoxLabel = uilabel(app.NLXEventFinderUIFigure);
            app.EventListListBoxLabel.Text = 'Event List';
            app.EventList = uilistbox(app.NLXEventFinderUIFigure);
            app.EventList.Items = {'...'};
            app.EventList.ValueChangedFcn = createCallbackFcn(app, @EventListValueChanged, true);
            app.EventList.Enable = 'off';
            app.EventList.Value = '...';
            app.StatusUpdateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StatusUpdateLabel.Text = {'Status '; 'Update'};
            app.statusMess = uitextarea(app.NLXEventFinderUIFigure);
            app.statusMess.Editable = 'off';
            app.DateLabel = uilabel(app.NLXEventFinderUIFigure);
            app.DateLabel.Text = 'Date:';
            app.StartTimeLabel = uilabel(app.NLXEventFinderUIFigure);
            app.StartTimeLabel.Text = 'Start Time:';
            app.dateField = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.startTfield = uieditfield(app.NLXEventFinderUIFigure, 'text');
            app.ContextMain = uicontextmenu(app.NLXEventFinderUIFigure);
            % Assign app.ContextMain
            app.NLXEventFinderUIFigure.ContextMenu = app.ContextMain;
            app.Menu = uimenu(app.ContextMain);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMain);
            app.Menu2.Text = 'Menu2';
            app.ContextEditMessage = uicontextmenu(app.NLXEventFinderUIFigure);
            % Assign app.ContextEditMessage
            app.statusMess.ContextMenu = app.ContextEditMessage;
            app.Back2top = uimenu(app.ContextEditMessage);
            app.Back2top.MenuSelectedFcn = createCallbackFcn(app, @Back2topMenuSelected, true);
            app.Back2top.Text = 'Back to top';
            app.Clear = uimenu(app.ContextEditMessage);
            app.Clear.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.Clear.Text = 'Clear all';
            % Show the figure after all components are created
            app.NLXEventFinderUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Event_Finder_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NLXEventFinderUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NLXEventFinderUIFigure)
        end
    end
end